classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button        matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        ENTERButton   matlab.ui.control.Button
        Button_13     matlab.ui.control.Button
        Button_14     matlab.ui.control.Button
        xButton       matlab.ui.control.Button
        Button_15     matlab.ui.control.Button
        Button_16     matlab.ui.control.Button
        Button_17     matlab.ui.control.Button
        CLEARButton   matlab.ui.control.Button
        EditField     matlab.ui.control.EditField
        MButton       matlab.ui.control.Button
        CLCMEMButton  matlab.ui.control.Button
        RMButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
         mem1
         mem2
    end
    
    methods (Access = private)
        
        function f = test(app)
            if app.mem1 == 'X'
                f = false;
            else
                f = true;
            end
        end
    end
           

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '1';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '1';
                 else
                     app.EditField.Value = strcat(app.EditField.Value,'1');
                 end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '2';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '2';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'2');
                 end
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '3';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '3';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'3');
                 end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '4';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '4';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'4');
                 end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '5';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '5';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'5');
                 end
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '6';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '6';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'6');
                 end
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '7';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '7';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'7');
                 end
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '8';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '8';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'8');
                 end
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '9';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '9';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'9');
                 end
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '0';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '0';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'0');
                 end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '.';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '.';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'.');
                 end
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.mem1 = 'X';
            if app.EditField.Value == '#'
                app.EditField.Value = '#';
            else
                app.EditField.Value = strcat(app.EditField.Value,'+');
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.mem1 = 'X';
            if app.EditField.Value == '#'
                app.EditField.Value = '#';
            else
                app.EditField.Value = strcat(app.EditField.Value,'-');
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            app.mem1 = 'X';
            if app.EditField.Value == '#'
                app.EditField.Value = '#';
            else
                app.EditField.Value = strcat(app.EditField.Value,'*');
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.mem1 = 'X';
            if app.EditField.Value == '#'
                app.EditField.Value = '#';
            else
                app.EditField.Value = strcat(app.EditField.Value,'/');
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '#';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '#';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,')');
                 end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            if app.test == 1 
                app.mem1 = 'X';
                app.EditField.Value = '(';
            else
                 if app.EditField.Value == '#'
                     app.EditField.Value = '(';
                 else
                 app.EditField.Value = strcat(app.EditField.Value,'(');
                 end
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.mem1 = 'X';
            if app.EditField.Value == '#'
                app.EditField.Value = '#';
            else
                app.EditField.Value = strcat(app.EditField.Value,'^');
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            default = '#';
            app.EditField.Value = default;
        end

        % Button pushed function: ENTERButton
        function ENTERButtonPushed(app, event)
            x = eval(app.EditField.Value);
            y = num2str(x);
            app.mem1 = y;
            app.EditField.Value = app.mem1;
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            app.mem2 = app.EditField.Value;
        end

        % Button pushed function: CLCMEMButton
        function CLCMEMButtonPushed(app, event)
            app.mem2 = '#';
        end

        % Button pushed function: RMButton
        function RMButtonPushed(app, event)
            if app.EditField.Value == '#'
                app.EditField.Value = app.mem2;
            elseif app.mem2 == '#'
                
            else
                app.EditField.Value = strcat(app.EditField.Value,app.mem2);
            end
        end

        % Callback function
        function MButton_2Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'btn_1';
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = 'btn_2';
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tag = 'btn_3';
            app.Button_3.Text = {'3'; ''};
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = 'btn_4';
            app.Button_4.Text = {'4'; ''};
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = 'btn_5';
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = 'btn_6';
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Tag = 'btn_7';
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tag = 'btn_8';
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Tag = 'btn_9';
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Tag = 'btn_0';
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Tag = 'btn_.';
            app.Button_11.Text = '.';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Tag = 'btn_/';
            app.Button_12.Text = '/';
            app.ENTERButton = uibutton(app.UIFigure, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.ENTERButton.Tag = 'btn_ENTER';
            app.ENTERButton.Text = 'ENTER';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Tag = 'btn_+';
            app.Button_13.Text = '+';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Tag = 'btn_-';
            app.Button_14.Text = '-';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Tag = 'btn_x';
            app.xButton.Text = 'x';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Tag = 'btn_^';
            app.Button_15.Text = '^';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Tag = 'btn_(';
            app.Button_16.Text = '(';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Tag = 'btn_)';
            app.Button_17.Text = ')';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Tag = 'btn_CLEAR';
            app.CLEARButton.Text = 'CLEAR';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '#';
            app.MButton = uibutton(app.UIFigure, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Tag = 'btn_rcm';
            app.MButton.Text = 'M+';
            app.CLCMEMButton = uibutton(app.UIFigure, 'push');
            app.CLCMEMButton.ButtonPushedFcn = createCallbackFcn(app, @CLCMEMButtonPushed, true);
            app.CLCMEMButton.Text = {'CLC '; 'MEM'};
            app.RMButton = uibutton(app.UIFigure, 'push');
            app.RMButton.ButtonPushedFcn = createCallbackFcn(app, @RMButtonPushed, true);
            app.RMButton.Tag = 'btn_m+';
            app.RMButton.Text = 'RM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end